classdef GloveBoxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnalysisPanel                  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        UIAxes_Image                   matlab.ui.control.UIAxes
        UIAxes_ROI                     matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ListofAvailableWebcamsonStartingAppTextArea  matlab.ui.control.TextArea
        ListofAvailableWebcamsonStartingAppTextAreaLabel  matlab.ui.control.Label
        EmailContentTextArea           matlab.ui.control.TextArea
        EmailContentTextAreaLabel      matlab.ui.control.Label
        EmailHeaderTextArea            matlab.ui.control.TextArea
        EmailHeaderTextAreaLabel       matlab.ui.control.Label
        GloveboxO2DetectionAppPanel    matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        RunAnalysisButton              matlab.ui.control.StateButton
        ShowCameraPreviewButton        matlab.ui.control.Button
        SelectO2NumberRegionButton     matlab.ui.control.Button
        EmailaddresstosendtoEditField  matlab.ui.control.EditField
        EmailaddresstosendtoEditFieldLabel  matlab.ui.control.Label
        O2ThesholdValueEditField       matlab.ui.control.NumericEditField
        O2ThesholdValueEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        camlist = string(webcamlist); % List of available webcams plugged in.
        cam = webcam; % Webcam object.
        roi_x_range = [];
        roi_y_range = [];
    end
    
    methods (Access = public)
        
        function results = thresholdImage(app, Image)
            J = imbinarize(Image,'global');
            imshow(J, 'Parent', app.UIAxes_ROI);
            CharacterSet = '0123456789. ';
            results = ocr(J, 'TextLayout', 'Line', 'CharacterSet', CharacterSet);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ListofAvailableWebcamsonStartingAppTextArea.Value = app.camlist;
        end

        % Button pushed function: SelectO2NumberRegionButton
        function SelectO2NumberRegionButtonPushed(app, event)
            img = snapshot(app.cam);
%             imshow(img, 'Parent', app.UIAxes_Image);
            
            I = im2gray(img);

%             ImFig = uifigure;
%             ImAxes = uiaxes(ImFig);
            ImFig = app.UIFigure;
            ImAxes = app.UIAxes_Image;
            imshow(I, 'Parent', ImAxes);
            uialert(ImFig, 'Define the region in which the O2 pressure number readout is', 'GloveBoxApp');
            pause(1);
            
            PressROI = drawrectangle(ImAxes);
            Positions = PressROI.Position;
            delete(PressROI);
            app.roi_x_range = round(Positions(2):(Positions(2)+Positions(4)));
            app.roi_y_range = round(Positions(1):(Positions(1)+Positions(3)));
            
%             delete(ImFig);
            imshow(img, 'Parent', app.UIAxes_Image);
            
            results = thresholdImage(app, img(app.roi_x_range, app.roi_y_range));
            
            try 
                wordString = string(results.Words{1,1});
                
                % This just makes sure that there's only one fullstop
                NumDots = count(wordString, '.');
                if NumDots > 1
                    wordString = char(wordString);
                    k = strfind(wordString,'.');
                    Dots2Remove = k(2:end);
                    wordString(Dots2Remove) = '';
                    wordString = string(wordString);
                end
                
                fprintf("Detected string of letters is %s\n", wordString)
                reading = double(wordString);
            catch
                uialert(ImFig, 'Could NOT detect any text or make it a double!', 'GloveBoxApp');
                return
            end
            
            if isnumeric(reading) == true
                alertMessage = sprintf('Found numeric value of %g', reading);
                disp(reading)
                uialert(ImFig, alertMessage, 'GloveBoxApp', 'Icon', 'success');
                app.RunAnalysisButton.Enable = 'on';
            else
                uialert(ImFig, 'Detected reading was NOT numeric!', 'GloveBoxApp');
                return
            end

            
        end

        % Button pushed function: ShowCameraPreviewButton
        function ShowCameraPreviewButtonPushed(app, event)
            preview(app.cam);
        end

        % Value changed function: RunAnalysisButton
        function RunAnalysisButtonValueChanged(app, event)
            value = app.RunAnalysisButton.Value;
            
            RecordingTable = table('Size',[0,2], 'VariableTypes', {'string', 'double'}, 'VariableNames', {'Time', 'Reading'});
            
            while value == 1
                
                cameraImage = snapshot(app.cam);
                
                roiImage = cameraImage(app.roi_x_range, app.roi_y_range, :);
                imshow(roiImage, 'Parent', app.UIAxes_Image);
                
                img = im2gray(roiImage);
                                
                results = thresholdImage(app, img);
                
                time = string(datestr(now, 'HH:MM:SS dd-mm-yy'));
                try
                    reading = double(string(results.Words{1,1}));
                catch ME
                    uialert(app.UIFigure, sprintf('Could not find words in analysis. Error is  %s', ME.message), 'GloveBoxApp');
                end
                
                
                table2add = table('Size',[1,2], 'VariableTypes', {'string', 'double'}, 'VariableNames', {'Time', 'Reading'});
                table2add(1,1) = table(time);
                table2add(1,2) = table(reading);
                RecordingTable = [RecordingTable; table2add];
                
                plot(app.UIAxes, table2array(RecordingTable(:,2)),'rx');
                
                if reading > app.O2ThesholdValueEditField.Value
                    warning(' O2 above threshold! @ %g %s', reading, time);
                    % https://uk.mathworks.com/matlabcentral/answers/346491-use-of-sendmail-function-to-send-an-email-from-a-gmail-account#answer_272161
                    setpref('Internet','SMTP_Server','smtp.gmail.com');
                    setpref('Internet','E_mail','AJWABAQUS@gmail.com');
                    setpref('Internet','SMTP_Username','AJWABAQUS');
                    setpref('Internet','SMTP_Password','20Th!nFo!ls@20');
                    props = java.lang.System.getProperties;
                    props.setProperty('mail.smtp.auth','true');
                    props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
                    props.setProperty('mail.smtp.socketFactory.port','465');
                    emailheader = string(app.EmailHeaderTextArea.Value);
                    mainbodytext = string(app.EmailContentTextArea.Value); % sprintf('The glovebox has an O2 level of %g at a time of %s', reading, time);
                    receivingEmail = string(app.EmailaddresstosendtoEditField.Value);
                    sendmail(receivingEmail, emailheader, mainbodytext);
                    uialert(app.UIFigure, sprintf('O2 theshold has been surpassed!\nEmail has been sent to %s', app.EmailaddresstosendtoEditField.Value), 'GloveBoxApp');
                    return
                end
                pause(5);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GloveboxO2DetectionAppPanel = uipanel(app.UIFigure);
            app.GloveboxO2DetectionAppPanel.Title = 'Glovebox O2 Detection App';
            app.GridLayout = uigridlayout(app.GloveboxO2DetectionAppPanel);
            app.O2ThesholdValueEditFieldLabel = uilabel(app.GridLayout);
            app.O2ThesholdValueEditFieldLabel.Text = 'O2 Theshold Value';
            app.O2ThesholdValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.O2ThesholdValueEditField.Limits = [0 Inf];
            app.O2ThesholdValueEditField.Value = 1;
            app.EmailaddresstosendtoEditFieldLabel = uilabel(app.GridLayout);
            app.EmailaddresstosendtoEditFieldLabel.Text = 'Email address to send to';
            app.EmailaddresstosendtoEditField = uieditfield(app.GridLayout, 'text');
            app.SelectO2NumberRegionButton = uibutton(app.GridLayout, 'push');
            app.SelectO2NumberRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectO2NumberRegionButtonPushed, true);
            app.SelectO2NumberRegionButton.Text = 'Select O2 Number Region';
            app.ShowCameraPreviewButton = uibutton(app.GridLayout, 'push');
            app.ShowCameraPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCameraPreviewButtonPushed, true);
            app.ShowCameraPreviewButton.Text = 'Show Camera Preview';
            app.RunAnalysisButton = uibutton(app.GridLayout, 'state');
            app.RunAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @RunAnalysisButtonValueChanged, true);
            app.RunAnalysisButton.Enable = 'off';
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.EmailHeaderTextAreaLabel = uilabel(app.UIFigure);
            app.EmailHeaderTextAreaLabel.Text = 'Email Header';
            app.EmailHeaderTextArea = uitextarea(app.UIFigure);
            app.EmailHeaderTextArea.Value = {'GLOVEBOX O2 LEVEL TOO HIGH'};
            app.EmailContentTextAreaLabel = uilabel(app.UIFigure);
            app.EmailContentTextAreaLabel.Text = 'Email Content';
            app.EmailContentTextArea = uitextarea(app.UIFigure);
            app.EmailContentTextArea.Value = {'The glovebox in the mechanical testing lab has surpassed the maximum allowed O2 level. Send for University Security Services now.'};
            app.ListofAvailableWebcamsonStartingAppTextAreaLabel = uilabel(app.UIFigure);
            app.ListofAvailableWebcamsonStartingAppTextAreaLabel.Text = 'List of Available Webcams on Starting App';
            app.ListofAvailableWebcamsonStartingAppTextArea = uitextarea(app.UIFigure);
            app.ListofAvailableWebcamsonStartingAppTextArea.Editable = 'off';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.GridLayout2 = uigridlayout(app.AnalysisPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'O2 Level Plot')
            xlabel(app.UIAxes, 'Entry Number')
            ylabel(app.UIAxes, 'O2 Level')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_ROI = uiaxes(app.GridLayout2);
            title(app.UIAxes_ROI, 'ROI')
            app.UIAxes_ROI.XTick = [];
            app.UIAxes_ROI.YTick = [];
            app.UIAxes_Image = uiaxes(app.GridLayout2);
            title(app.UIAxes_Image, 'Raw Image')
            app.UIAxes_Image.XTick = [];
            app.UIAxes_Image.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GloveBoxApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end